classdef Data_Processing_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        SelectFileTab_2           matlab.ui.container.Tab
        FastPassButton            matlab.ui.control.Button
        ConfirmButton             matlab.ui.control.Button
        UpdateButton              matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        InfoLabel                 matlab.ui.control.Label
        ImportmetadataButton      matlab.ui.control.Button
        SelectDirectoryButton     matlab.ui.control.Button
        PrePostCollapseTab        matlab.ui.container.Tab
        PrePostCollapseFigure     matlab.ui.control.UIAxes
        VoltageSliderTab          matlab.ui.container.Tab
        VoltageSliderPanel        matlab.ui.container.Panel
        VoltagePlotTab            matlab.ui.container.Tab
        GroupByDropDown           matlab.ui.control.DropDown
        GroupByDropDownLabel      matlab.ui.control.Label
        VoltagePlot               matlab.ui.control.UIAxes
        MicroplatePlotTab         matlab.ui.container.Tab
        Microplateplot            matlab.ui.control.UIAxes
        MicroplateSliderTab       matlab.ui.container.Tab
        ImagingModeDropDown       matlab.ui.control.DropDown
        ImagingModeDropDownLabel  matlab.ui.control.Label
        MicroplateSlider          matlab.ui.control.Slider
        SliderLabel               matlab.ui.control.Label
        MicroplateSliderViewer    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ScanType;
        Data_directory = '*Not yet imported*';
        MetadataFileName = '*Not yet imported*';
        MetadataPathName = '';
        Parameters;
        DImi;
        SampROI_means;
        SampCNRs;
        Maxs;
        P;
        PlateSize;
        N_Frames;
        Imaging_mode;
        Full_Map; % maps well locations to another map that maps parameters to values
        % example full_map {A1: {color: blue, size: large, weight: 100}, A2: {color: red, size: small, weight:50}}
    end

    methods (Access = private)

        function update(app)
            %This function updates the Infolabel with the information stored in the properties.
            % It will also update the Ramp Label with the Voltages if needed
            string1 = ['Metadata File:' newline '    ' app.Data_directory];
            string2 = ['Metadata File:' newline '    ' app.MetadataPathName '\' app.MetadataFileName];
            app.InfoLabel.Text = [string1 newline string2];
            if strcmp(app.ScanType, 'Voltage Ramp') || strcmp(app.ScanType, 'Collapse Ramp')
                app.RampLabel.Text = convertStringsToChars(strjoin(['Voltage Values:' newline string(app.NumField1.Value:app.NumField3.Value:app.NumField2.Value)]));
            end
        end

        function full_map = extract_metadata(app)
            %This function will extract information for each well from the
            %metadata. It will also raise errors and return a blank if
            %there are incompatability issues with the metadata file

            %It will return the full_map which maps well locations to
            %another map that maps parameters to values
            % example full_map {A1: {color: blue, size: large, weight: 100}, A2: {color: red, size: small, weight:50}}

            %file provided by the user
            if isempty(app.MetadataPathName) || isempty(app.MetadataFileName)
                return %don't proceed if the user has not provided a metadata file
            end
            metadata_cell = readcell([app.MetadataPathName '\' app.MetadataFileName]);
            size_cell = size(metadata_cell);
            wells_used = metadata_cell(2:size_cell(1)); %take all the elements in the well_used column
            full_map = containers.Map;

            %check that each of the wells are unique
            if length(wells_used) ~= length(unique(wells_used))
                raise_error(app, 'There exists a duplicate well');
                return;
            end

            transpose_metadata = metadata_cell'; %take transpose of metadata cell. Makes the code easier
            parameters = transpose_metadata(2:size_cell(2)); %take all the parameters
            app.Parameters = parameters;

            %iterate through the wells, check each one, and populate full map
            %The keys for full_map are strings for the wells (ex. A1), the
            %values will be maps
            start_index = size_cell(2)+2;
            for well = wells_used
                well_string = well{1}; %Have to do this so you have a string instead of a cell containing a string
                error_msg = [well_string 'is an invalid well name'];

                %check if the well string starts with letters A - H
                if ~ismember(well_string(1), 'ABCDEFGH')
                    raise_error(app, error_msg);
                    full_map = containers.Map;
                    return;
                end

                %check if the well strings ends with numbers 1 - 12
                column_num = str2double(well_string(2:end));
                if isnan(column_num)
                    raise_error(app, error_msg);
                    full_map = containers.Map;
                    return
                end
                if ~ismember(column_num, 1:1:12)
                    raise_error(app, error_msg);
                    full_map = containers.Map;
                    return
                end

                %populate the map
                well_values = transpose_metadata(start_index:start_index + size_cell(2)-2);
                full_map(well_string) = containers.Map(parameters, well_values);
            end
            app.Full_map = full_map;
        end

        function raise_error(app, message)
            %This is a helper function that opens up a new window and displays error messages to the user
            h=errordlg(message,'Error');
            set(h, 'WindowStyle', 'modal');
            uiwait(h);
        end

        function plot_prepost_collapse(app)
            %This function plots the pre post collapse image on the prepost
            %collapse tab.

            %             % Convert interpolated difference image to dB, cropping based on disp_depth
            %             Im_disp = 20*log10(squeeze(abs(dImi(iZd,:,imgMode,:))));
            %             % Calculate noise value
            %             noise_disp = max(mean(mean(20*log10(squeeze(abs(dImi(iZd_noise,:,imgMode,:)))))))- min(min(min(Im_disp)));
            %             % Subtract smallest value from all values
            %             Im_disp = Im_disp - min(Im_disp,[],[1:3]);
            %
            %             % get x and z sizes and number of wells
            %             [zsize,~,Nw] = size(Im_disp);
            %             xsize = round(zsize*6.4/diff(disp_depth));
            %             % resize images and add RGB colormaps
            %             % Imgs dimensions: zs, xs, colors, wells
            %             dImgs = nan(zsize,xsize,3,Nw);
            %             for well = 1:Nw
            %                 % resize image
            %                 temp = imresize(Im_disp(:,:,well),[zsize xsize]);
            %                 % convert resized grayscale image to RGB and add to array
            %                 dImgs(:,:,:,well) = real2rgb(temp,colorm,[noise_disp max(max(temp))+disp_crange(2)]);
            %             end
            size_DImi = size(app.DImi);
            %for 
            montage([app.DImi(:,:,15,1)],'Size',app.PlateSize,'Parent',app.PrePostCollapseFigure);
            %             noise_disp = evalin('base', 'noise_disp');
            %             disp_crange = evalin('base', 'disp_crange');
            %             temp = evalin('base', 'temp');
            %             colorbar(app.PrePostCollapseFigure, 'Ticks',[0 1],'TickLabels',[noise_disp max(max(temp))+disp_crange(2)]);
            %             app.PrePostCollapseFigure.Title.String = evalin('base', 'FigTitle');
        end

        function plot_voltageramp_slider(app)
            %This function plots the voltage slider on the voltage slider tab.
            % visualize one montage per pressure with sliceViewer
            montages = []; % initalize montage images array
            size_dImis = size(app.DImi);
            for pressure = 1:app.N_Frames
                m = montage(squeeze(app.dImi(:,:,pressure,1,:)),'Size',app.PlateSize); % create a montage image
                montages = cat(3,montages,m.CData); % add montage image to montage array
            end
            sliceViewer(montages, 'Colormap', hot(256), 'Parent',app.VoltageSliderPanel, 'DisplayRange',[20 400], DisplayRangeInteraction, 'on');
        end

        function plot_microplate_plot(app)
            %This function plots the microplate plot on the microplate plot
            %tab
            %             UImicroplateplot(app.Microplateplot, app.Quant_ROIs(:,1:12));
            app.Maxs = evalin('base', 'maxs');
            UImicroplateplot(app.Microplateplot, app.Maxs(:,:,1));
        end

        function plot_microplate_slider(app)
            %This function plots the microplate slider on the microplate
            %slider tab
            UImicroplateplot(app.MicroplateSliderViewer, app.SampCNRs(:,:,1,1));

            %controls for microplate slider
%             app.MicroplateSlider.Limits = [10 15];%[app.P.seed(1) app.P.seed(end)];
%             app.MicroplateSlider.MajorTicks = [10, 11, 12, 13, 14, 15];%app.P.seed;
%             app.MicroplateSlider.MajorTickLabels = string([10, 11, 12, 13, 14, 15]); %string(app.P.seed);
        end

        function group_map = make_group_map(app, variable)
            %Given a variable from the metadata file provided, this
            %function makes a map that maps the unique variable values to
            %their well values.
            if isempty(app.MetadataPathName) || isempty(app.MetadataFileName)
                return %don't proceed if the user has not provided a metadata file
            end
            group_map = containers.Map;
            metadata_table = readtable([app.MetadataPathName '\' app.MetadataFileName]);
            metadata_size = size(metadata_table);
            variable_column = metadata_table.(variable);
            wells_column = metadata_table.('Wells');
            for i = 1:metadata_size(1) %iterate through the rows of the metadata file
                if ~any(strcmp(keys(group_map),variable_column{i})) %if variable_column(i) not in parameter_map.keys
                    group_map(variable_column{i}) = convertCharsToStrings(wells_column{i});%add to map {key = variable_column(i): value = wells_column(i)}
                else
                    group_map(variable_column{i}) = [group_map(variable_column{i}) convertCharsToStrings(wells_column{i})];
                end
            end
        end

        function plot_voltage_line(app)
            %This function plots the line plot of the average voltages on the
            %Voltage line plot slide if the scan type is voltage ramp or collapse. If the scantype is
            % the pre-post collapse, it will plot a bar graph. It will also groups wells together
            % based on what the user selects for.
            reset(app.VoltagePlot); %delete what was previously on the VoltagePlot
            value = app.GroupByDropDown.Value;
            SampCNRs_size = size(app.SampCNRs); %need this for the rows and columns
            master_table = array2table(zeros(SampCNRs_size(3), 1)); %initialize the table with one column of zeroes
            if strcmp(value, 'Rows')
                master_table = array2table(zeros(app.SampCNRs(3), 1)); %initialize the table with one column of zeroes
                for row_num = 1:SampCNRs_size(1)
                    table_col = {};
                    for voltage = 1:SampCNRs_size(3)
                        table_col = [table_col; mean(app.SampCNRs(row_num,:,voltage,1))] %add new element to column element
                    end
                    master_table.(['Row ' num2str(row_num)]) = table_col; %add the column to the table
                end
                master_table = removevars(master_table,{'Var1'}); %delete the extra column of zeroes that was made
            end

            if strcmp(value, 'Columns')
                master_table = array2table(zeros(SampCNRs_size(3), 1)); %initialize the table with one column of zeroes
                for col_num = 1:SampCNRs_size(2)
                    table_col = {};
                    for voltage = 1:SampCNRs_size(3)
                        table_col = [table_col; mean(app.SampCNRs(:,col_num,voltage,1))]; %add new element to column element
                    end
                    master_table.(['Column ' num2str(col_num)]) = table_col; %add the column to the table
                end
                master_table = removevars(master_table,{'Var1'}); %delete the extra column of zeroes that was made
            end

            if ~strcmp(value, 'Columns') && ~strcmp(value, 'Rows') %it will be one the metadata parameters
                master_table = array2table(zeros(SampCNRs_size(3), 1)); %initialize the table with one column of zeroes
                group_map = make_group_map(app, value); %make the group map with the helper function
                group_map_keys = group_map.keys;
                key_size = size(group_map.keys);
                for key_num = 1:key_size(2) %for every group
                    group = group_map(group_map_keys{key_num});
                    group_size = size(group);
                    group_matrix = [];
                    for i = 1:group_size(2) %for well in group
                        well_column = [];
                        well = char(group(i));
                        row_num = find('ABCDEFGH'== well(1));
                        col_num = str2double(well(2:end));
                        for voltage = 1:SampCNRs_size(3) %for every voltage
                            well_column = [well_column app.SampCNRs(row_num,col_num,voltage,1)];
                        end
                        group_matrix = [group_matrix; well_column];
                    end
                    master_table.(group_map_keys{key_num}) = mean(group_matrix',2); %add the column to the table
                end
                master_table = removevars(master_table,{'Var1'}); %delete the extra column of zeroes that was made
            end

            variables = master_table.Properties.VariableNames;
            variables_size = size(variables);
            if strcmp(app.ScanType, 'Pre-post Collapse') %create bar graph
                values = []; %values for bar graph
                for column_num = 1:width(master_table)
                    value_pair = cell2mat(master_table.(variables{column_num})(1:2)'); %get the values for a specific column
                    % and store it in the pair [pre-collapse post-collapse] array
                    values = [values; value_pair];
                end
                assignin('base',"master_table_values",values);
                b = bar(app.VoltagePlot, categorical(variables), values); %set voltage plot to bar graph
                set(b, 'DisplayName', ['Pre-collapse','Post-collapse']);
                return;
            else %create a line plot
                hold(app.VoltagePlot,'on')
                for i = 1:variables_size(2)
                    plot(app.VoltagePlot, 1:SampCNRs_size(3), cell2mat(master_table.(variables{i})), 'DisplayName',variables{i});
                end
                hold(app.VoltagePlot,'off')
            end
        end

        function populate_imaging_mode(app)
            value = app.ImagingModeDropDown.Value;
            if strcmp(value, 'xAM')
                app.Imaging_mode = 1;
            end
            if strcmp(value, 'B mode')
                app.Imaging_mode = 2;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            populate_imaging_mode(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            update(app);
        end

        % Button pushed function: ImportmetadataButton
        function ImportmetadataButtonPushed(app, event)
            [app.MetadataFileName, app.MetadataPathName] = uigetfile({'*.csv';'*.xlsx';'*.xls'},'Select Data Files to Use','MultiSelect','off');
            update(app);
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            [app.Data_directory, ] = uigetdir;
            update(app);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            assignin('base',"pathName", app.Data_directory);
            %scripts to be run
            %evalin('base', 'PlateProc1_GUI'); %used to be raw2imgs
            %evalin('base', 'autoROI3_GUI'); %used to be imgs2processed
            evalin('base', 'PlateQuan4_split_GUI');

            %taking data from the workspace
            app.dImi = evalin('base','dImi');
            app.SampROI_means = evalin('base', 'sampROI_means');
            app.SampCNRs = evalin('base', 'sampCNRs');
            app.PlateSize = evalin('base', 'PlateSize');
            app.N_Frames = evalin('base', 'Nf');
            app.sampCNRs = permute(reshape(sampCNR, app.N_Frames, 2, app.PlateSize(2), app.PlateSize(1)), [4 3 1 2]);
            app.Dimi = permute(reshape(app.DImi, app.N_Frames, 2, app.PlateSize(2), app.PlateSize(1)), [4 3 1 2]);
            app.P = evalin('base', 'P');

            app.ScanType = 'Pre-post Collapse'; %DELETE LATER !! normally this would be set by the Data Acquisition GUI

            plot_microplate_plot(app);
            plot_voltage_line(app);
            extract_metadata(app);
            app.PrePostCollapseFigure % populate the pre-post-collapse figure
            app.GroupByDropDown.Items = [app.GroupByDropDown.Items, app.Parameters];
        end

        % Value changed function: GroupByDropDown
        function GroupByDropDownValueChanged(app, event)
            plot_voltage_line(app);
        end

        % Button pushed function: FastPassButton
        function FastPassButtonPushed(app, event)
            app.DImi = evalin('base','dImi');
            app.SampROI_means = evalin('base', 'sampROI_means');
            app.SampCNRs = evalin('base', 'sampCNRs');
            app.P = evalin('base', 'P');
            app.PlateSize = evalin('base', 'PlateSize');
            app.N_Frames = evalin('base', 'Nf');
            %app.SampCNRs = permute(reshape(app.SampCNRs, app.N_Frames, 2, app.PlateSize(2), app.PlateSize(1)), [4 3 1 2]);
            %reshape images in dImi, DO IT BEFORE PERMUTE BELOW iterate through all images and
            %resize using imresize(app.DImi[index of image], [zsize xsize]);
            
            % convert resized grayscale image to RGB and add to array
            %dImgs(:,:,:,well) = real2rgb(temp,colorm,[noise_disp max(max(temp))+disp_crange(2)]);
            
            size_DImi = size(app.DImi);
            for pressure = 1:(size_DImi(1)-1)
                for im_mode = 1:size_DImi(2)
                    for image = 1:size_DImi(3)
                        app.DImi(pressure, im_mode, image) = mat2cell(imresize(cell2mat(app.DImi(pressure, im_mode, image)), [100 100]), 100, 100);
                        %app.DImi(pressure, im_mode, image) = imshow(real2rgb(temp, 'hot', [0 1000]));
                    end
                end
            end
            app.DImi = permute(reshape(app.DImi, app.N_Frames-1, 2, app.PlateSize(2), app.PlateSize(1)), [4 3 1 2]);

            app.ScanType = 'Voltage Ramp'; %normally this would be set by the Data Acquisition GUI

            plot_prepost_collapse(app);
            plot_microplate_plot(app);
            plot_voltage_line(app);

            if strcmp(app.ScanType, 'Pre-post Collapse')
                app.MicroplateSlider.Visible = 'off';
                app.MicroplateSliderViewer.Visible = 'off';
                return
            end
            %plot_voltageramp_slider(app);
            plot_microplate_slider(app);
        end

        % Value changed function: MicroplateSlider
        function MicroplateSliderValueChanged(app, event)
            value = round(app.MicroplateSlider.Value);
            UImicroplateplot(app.MicroplateSliderViewer, app.SampCNRs(:,:,value,app.Imaging_mode));
        end

        % Value changed function: ImagingModeDropDown
        function ImagingModeDropDownValueChanged(app, event)
            populate_imaging_mode(app);
            plot_microplate_slider(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectFileTab_2 = uitab(app.TabGroup);
            app.SelectFileTab_2.Title = 'Select File';
            app.Panel_2 = uipanel(app.SelectFileTab_2);
            app.SelectDirectoryButton = uibutton(app.Panel_2, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Data Directory';
            app.ImportmetadataButton = uibutton(app.Panel_2, 'push');
            app.ImportmetadataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmetadataButtonPushed, true);
            app.ImportmetadataButton.Text = 'Import metadata';
            app.InfoLabel = uilabel(app.Panel_2);
            app.InfoLabel.Text = {'Data Directory:'; '    *Not yet imported* '; ''; 'Metadata File:'; '    *Not yet imported* '; ''};
            app.UpdateButton = uibutton(app.SelectFileTab_2, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ConfirmButton = uibutton(app.SelectFileTab_2, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.FastPassButton = uibutton(app.SelectFileTab_2, 'push');
            app.FastPassButton.ButtonPushedFcn = createCallbackFcn(app, @FastPassButtonPushed, true);
            app.FastPassButton.Text = 'Fast Pass Button (Remove Later)';
            app.PrePostCollapseTab = uitab(app.TabGroup);
            app.PrePostCollapseTab.Title = 'Pre-Post Collapse';
            app.PrePostCollapseFigure = uiaxes(app.PrePostCollapseTab);
            xlabel(app.PrePostCollapseFigure, 'X')
            ylabel(app.PrePostCollapseFigure, 'Y')
            app.VoltageSliderTab = uitab(app.TabGroup);
            app.VoltageSliderTab.Title = 'Voltage Slider';
            app.VoltageSliderPanel = uipanel(app.VoltageSliderTab);
            app.VoltagePlotTab = uitab(app.TabGroup);
            app.VoltagePlotTab.Title = 'Voltage Plot';
            app.VoltagePlot = uiaxes(app.VoltagePlotTab);
            app.GroupByDropDownLabel = uilabel(app.VoltagePlotTab);
            app.GroupByDropDownLabel.Text = 'Group By';
            app.GroupByDropDown = uidropdown(app.VoltagePlotTab);
            app.GroupByDropDown.Items = {'Columns', 'Rows'};
            app.GroupByDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupByDropDownValueChanged, true);
            app.GroupByDropDown.Value = 'Columns';
            app.MicroplatePlotTab = uitab(app.TabGroup);
            app.MicroplatePlotTab.Title = 'Microplate Plot';
            app.Microplateplot = uiaxes(app.MicroplatePlotTab);
            title(app.Microplateplot, 'Max signal achieved at any voltage')
            app.MicroplateSliderTab = uitab(app.TabGroup);
            app.MicroplateSliderTab.Title = 'Microplate Slider';
            app.MicroplateSliderViewer = uiaxes(app.MicroplateSliderTab);
            xlabel(app.MicroplateSliderViewer, 'X')
            ylabel(app.MicroplateSliderViewer, 'Y')
            app.SliderLabel = uilabel(app.MicroplateSliderTab);
            app.SliderLabel.Text = 'Slider';
            app.MicroplateSlider = uislider(app.MicroplateSliderTab);
            app.MicroplateSlider.Limits = [2 25];
            app.MicroplateSlider.ValueChangedFcn = createCallbackFcn(app, @MicroplateSliderValueChanged, true);
            app.MicroplateSlider.MinorTicks = [];
            app.MicroplateSlider.Value = 2;
            app.ImagingModeDropDownLabel = uilabel(app.MicroplateSliderTab);
            app.ImagingModeDropDownLabel.Text = 'Imaging Mode';
            app.ImagingModeDropDown = uidropdown(app.MicroplateSliderTab);
            app.ImagingModeDropDown.Items = {'xAM', 'B mode'};
            app.ImagingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagingModeDropDownValueChanged, true);
            app.ImagingModeDropDown.Value = 'xAM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Processing_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end